classdef LSTMDDToolBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        DetectDamageTab              matlab.ui.container.Tab
        LocationButtonGroup          matlab.ui.container.ButtonGroup
        YaxislocationButton          matlab.ui.control.RadioButton
        XaxislocationButton          matlab.ui.control.RadioButton
        ZaxislocationButton          matlab.ui.control.RadioButton
        ConfigureSimulationTab_2     matlab.ui.container.Tab
        ResolutionPanel              matlab.ui.container.Panel
        EditField_2                  matlab.ui.control.EditField
        RadiusPanel                  matlab.ui.container.Panel
        EditField_6                  matlab.ui.control.EditField
        HeightPanel                  matlab.ui.container.Panel
        EditField_3                  matlab.ui.control.EditField
        LSTMarchitectureButtonGroup  matlab.ui.container.ButtonGroup
        netButton                    matlab.ui.control.RadioButton
        optionsButton                matlab.ui.control.RadioButton
        layersButton                 matlab.ui.control.RadioButton
        StartTrainingButton          matlab.ui.control.Button
        ConfigureDescription         matlab.ui.control.TextArea
        DescriptionTextAreaLabel     matlab.ui.control.Label
        SimulationCountPanel         matlab.ui.container.Panel
        SimCountSpinner              matlab.ui.control.Spinner
        HyperparametersListBox       matlab.ui.control.ListBox
        HyperparametersListBoxLabel  matlab.ui.control.Label
        LearningRatePanel            matlab.ui.container.Panel
        EditField                    matlab.ui.control.EditField
        NormalisationPanel           matlab.ui.container.Panel
        NormaliseDataCheckBox        matlab.ui.control.CheckBox
        EpochsPanel                  matlab.ui.container.Panel
        EpochsLabel                  matlab.ui.control.Label
        EpochsSlider                 matlab.ui.control.Slider
        UIAxes                       matlab.ui.control.UIAxes
        LSTMModelTab                 matlab.ui.container.Tab
        Accuracy                     matlab.ui.control.Label
        ModelListBox                 matlab.ui.control.ListBox
        ModelListBoxLabel            matlab.ui.control.Label
        ModelDescription             matlab.ui.control.TextArea
        DescriptionTextArea_3Label   matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ConfusionAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        toolbox  % redux store for application
        log % log4m logger
    end
    
    methods (Access = private)

        % Making the input panels unvisible, they become visible when an
        % individual parameter is selected
        function init_ui(app)            
            app.EpochsSlider.Value = app.toolbox.get_tpps_epochs();
            app.SimCountSpinner.Value = app.toolbox.get_tpps_simulation_count();
            hidePanels(app);
        end
        
        function hidePanels(app)
            app.NormalisationPanel.Visible=false;
            app.EpochsPanel.Visible=false;
            app.LearningRatePanel.Visible=false;
            app.ResolutionPanel.Visible=false;
            app.RadiusPanel.Visible=false;
            app.HeightPanel.Visible=false;
            app.SimulationCountPanel.Visible=false;            
        end
    end
    
    methods (Access = public)
        
        % This calls the ToolBoxActions which is the object
        function update(app, event)
            
             switch(event.action)
                case ToolBoxActions.TOOLBOX_OBSERVER_ATTACHED
                  general_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_START_ANGLE
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_END_ANGLE
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_RADIUS
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_HEIGHT
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_PLOT_GRAPHS
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_RESOLUTION
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_SIMULATION_COUNT
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.TPPS_EDIT_EPOCHS
                  tpps_reducer(app,event);
                  
                case ToolBoxActions.T_STARTED
                  t_reducer(app,event);
                  
                case ToolBoxActions.TPS_RDATA_READY
                    tps_reducer(app,event);
                    
                case ToolBoxActions.TPS_RCLASSES_READY
                    tps_reducer(app,event);
 
                case ToolBoxActions.G_DESCRIPTION_CHANGE
                    app.log=start_logger(app);
                 
                case ToolBoxActions.G_UID_SET
                    tpps_reducer(app,event);
                  
                case ToolBoxActions.MB_ACCURACY_SET
                    m_reducer(app,event);
                    
                case ToolBoxActions.M_NETWORK_SET
                    m_reducer(app,event);
                    
                case ToolBoxActions.MT_DATA_TEST_SPLIT
                    mt_reducer(app,event);
                    
                case ToolBoxActions.MT_CLASSES_TEST_SPLIT
                    mt_reducer(app,event);
                    
                case ToolBoxActions.MT_CLASSES_PRED_READY
                    mt_reducer(app,event);
                  
                case ToolBoxActions.MB_CONF_MATRIX_READY
                    m_reducer(app,event);
  
                otherwise 
                  msgbox(join(['Reducer Action Not Implemented:' string(event.action)]),mfilename)
             end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.toolbox = store(); 
            app.toolbox.helper.starting=true;
            app.log=start_logger(app);
            app.log.info(mfilename,join(['Log started at toolbox/logger/',app.toolbox.get_g_uid(),'.txt'],''))
            app.toolbox.attach_observer(app);
            init_ui(app);
        end

        % Button pushed function: StartTrainingButton
        function RunTrainingButtonPushed(app, event)
            mylstm2(app)
        end

        % Callback function
        function HyperParamChanged(app, event)
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: EpochsSlider
        function EpochsEditFieldValueChanged(app, event)
%                 app.log.debug(mfilename, join(['epoch event value is ' string(event.Value)]));
                app.toolbox.set_tpps_epochs(event.Value);
        end

        % Callback function: HyperparametersListBox, ResolutionPanel
        function HyperparametersListBoxValueChanged(app, event)
            hidePanels(app);
%             disp(event);
            switch(event.Value)
                case 'Normalise'
                  app.NormalisationPanel.Visible=true;
                case 'Learning Rate'
                  app.LearningRatePanel.Visible=true;
                case 'Radius'
                  app.RadiusPanel.Visible=true;
                case 'Resolution'
                  app.ResolutionPanel.Visible=true;
                case 'Height'
                  app.HeightPanel.Visible=true;
                case 'Epochs'
                  app.EpochsPanel.Visible=true;
                case 'Simulation count'
                  app.SimulationCountPanel.Visible=true;
                otherwise 
                  msgbox(strcat(event.value,' not implemented'))
            end
        end

        % Value changed function: SimCountSpinner
        function SimCountChanged(app, event)
            value = app.SimCountSpinner.Value;
            app.toolbox.set_tpps_simulation_count(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DetectDamageTab = uitab(app.TabGroup);
            app.DetectDamageTab.Title = 'Detect Damage';
            app.LocationButtonGroup = uibuttongroup(app.DetectDamageTab);
            app.LocationButtonGroup.Title = 'Location';
            app.ZaxislocationButton = uiradiobutton(app.LocationButtonGroup);
            app.ZaxislocationButton.Text = 'Z-axis location';
            app.ZaxislocationButton.Value = true;
            app.XaxislocationButton = uiradiobutton(app.LocationButtonGroup);
            app.XaxislocationButton.Text = 'X-axis location';
            app.YaxislocationButton = uiradiobutton(app.LocationButtonGroup);
            app.YaxislocationButton.Text = 'Y-axis location';
            app.ConfigureSimulationTab_2 = uitab(app.TabGroup);
            app.ConfigureSimulationTab_2.Title = 'Configure Simulation';
            app.UIAxes = uiaxes(app.ConfigureSimulationTab_2);
            title(app.UIAxes, '3D View')
            xlabel(app.UIAxes, 'sideview')
            ylabel(app.UIAxes, 'height')
            zlabel(app.UIAxes, 'Z')
            app.EpochsPanel = uipanel(app.ConfigureSimulationTab_2);
            app.EpochsSlider = uislider(app.EpochsPanel);
            app.EpochsSlider.Limits = [10 250];
            app.EpochsSlider.MajorTicks = [10 50 100 150 200 250];
            app.EpochsSlider.ValueChangedFcn = createCallbackFcn(app, @EpochsEditFieldValueChanged, true);
            app.EpochsSlider.Value = 50;
            app.EpochsLabel = uilabel(app.EpochsPanel);
            app.EpochsLabel.Text = 'Epochs: ';
            app.NormalisationPanel = uipanel(app.ConfigureSimulationTab_2);
            app.NormalisationPanel.Title = 'Normalisation';
            app.NormaliseDataCheckBox = uicheckbox(app.NormalisationPanel);
            app.NormaliseDataCheckBox.Text = 'Normalise Data';
            app.LearningRatePanel = uipanel(app.ConfigureSimulationTab_2);
            app.LearningRatePanel.Title = 'Learning Rate';
            app.EditField = uieditfield(app.LearningRatePanel, 'text');
            app.HyperparametersListBoxLabel = uilabel(app.ConfigureSimulationTab_2);
            app.HyperparametersListBoxLabel.Text = 'Hyperparameters';
            app.HyperparametersListBox = uilistbox(app.ConfigureSimulationTab_2);
            app.HyperparametersListBox.Items = {'Normalise', 'Epochs', 'Learning Rate', 'Resolution', 'Height', 'Radius', 'Simulation count'};
            app.HyperparametersListBox.ValueChangedFcn = createCallbackFcn(app, @HyperparametersListBoxValueChanged, true);
            app.HyperparametersListBox.Value = 'Normalise';
            app.SimulationCountPanel = uipanel(app.ConfigureSimulationTab_2);
            app.SimulationCountPanel.Title = 'Simulation Count';
            app.SimCountSpinner = uispinner(app.SimulationCountPanel);
            app.SimCountSpinner.Limits = [1 100];
            app.SimCountSpinner.ValueChangedFcn = createCallbackFcn(app, @SimCountChanged, true);
            app.SimCountSpinner.Value = 1;
            app.DescriptionTextAreaLabel = uilabel(app.ConfigureSimulationTab_2);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.ConfigureDescription = uitextarea(app.ConfigureSimulationTab_2);
            app.StartTrainingButton = uibutton(app.ConfigureSimulationTab_2, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrainingButtonPushed, true);
            app.StartTrainingButton.Tooltip = {'This will run the programme'};
            app.StartTrainingButton.Text = 'Start Training';
            app.LSTMarchitectureButtonGroup = uibuttongroup(app.ConfigureSimulationTab_2);
            app.LSTMarchitectureButtonGroup.Title = 'LSTM architecture';
            app.layersButton = uiradiobutton(app.LSTMarchitectureButtonGroup);
            app.layersButton.Text = 'layers';
            app.layersButton.Value = true;
            app.optionsButton = uiradiobutton(app.LSTMarchitectureButtonGroup);
            app.optionsButton.Text = 'options';
            app.netButton = uiradiobutton(app.LSTMarchitectureButtonGroup);
            app.netButton.Text = 'net';
            app.HeightPanel = uipanel(app.ConfigureSimulationTab_2);
            app.HeightPanel.Title = 'Height';
            app.EditField_3 = uieditfield(app.HeightPanel, 'text');
            app.RadiusPanel = uipanel(app.ConfigureSimulationTab_2);
            app.RadiusPanel.Title = 'Radius';
            app.EditField_6 = uieditfield(app.RadiusPanel, 'text');
            app.ResolutionPanel = uipanel(app.ConfigureSimulationTab_2);
            app.ResolutionPanel.Title = 'Resolution';
            app.ResolutionPanel.SizeChangedFcn = createCallbackFcn(app, @HyperparametersListBoxValueChanged, true);
            app.EditField_2 = uieditfield(app.ResolutionPanel, 'text');
            app.LSTMModelTab = uitab(app.TabGroup);
            app.LSTMModelTab.Title = 'LSTM Model';
            app.ConfusionAxes = uiaxes(app.LSTMModelTab);
            title(app.ConfusionAxes, 'Confusion Chart')
            xlabel(app.ConfusionAxes, 'Predicted')
            ylabel(app.ConfusionAxes, 'Actual')
            zlabel(app.ConfusionAxes, 'Z')
            app.UITable = uitable(app.LSTMModelTab);
            app.UITable.ColumnName = {'Net'; 'Accuracy'; 'TP'; 'TN'; 'FP'; 'FN'};
            app.UITable.RowName = {};
            app.UITable.Tooltip = {'False Negative'};
            app.DescriptionTextArea_3Label = uilabel(app.LSTMModelTab);
            app.DescriptionTextArea_3Label.Text = 'Description';
            app.ModelDescription = uitextarea(app.LSTMModelTab);
            app.ModelDescription.Value = {'Normalise: True'; 'Epochs: 50'; 'Learning rate: 0.0001'; 'Resolution: 0.05 mm'; 'Radius: 10 mm'; 'Height: 15 mm'; 'Simulation count: 2'; ''};
            app.ModelListBoxLabel = uilabel(app.LSTMModelTab);
            app.ModelListBoxLabel.Text = 'Model';
            app.ModelListBox = uilistbox(app.LSTMModelTab);
            app.ModelListBox.Items = {'Accuracy'};
            app.ModelListBox.Value = 'Accuracy';
            app.Accuracy = uilabel(app.LSTMModelTab);
            app.Accuracy.Text = '0.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSTMDDToolBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end